classdef ProjectPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DataMenu                        matlab.ui.container.Menu
        ShowdatanodeMenu                matlab.ui.container.Menu
        SaveplotsMenu                   matlab.ui.container.Menu
        ExportplotMenu                  matlab.ui.container.Menu
        ExportallplotsMenu              matlab.ui.container.Menu
        PlotsMenu                       matlab.ui.container.Menu
        AddnewplotMenu                  matlab.ui.container.Menu
        xyMenu                          matlab.ui.container.Menu
        xyy2Menu                        matlab.ui.container.Menu
        xyx2y2Menu                      matlab.ui.container.Menu
        TensileShearMenu                matlab.ui.container.Menu
        DeletecurrentplotMenu           matlab.ui.container.Menu
        LegendMenu                      matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        TypeofplotandnumberofplotLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Axes; %1-n axis created by user
        Tabs; %2-n tabs created by user
        DataCore; %datacore retrived from project explorer
        Presets; %tables of setting created for each plot
        PEH; %project explorer handle
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            tab1 = uitab(app.TabGroup,'Title','Plot 2');
            app.Axes{1}=uiaxes(tab1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.ShowdatanodeMenu = uimenu(app.DataMenu);
            app.ShowdatanodeMenu.Text = 'Show data node';
            app.SaveplotsMenu = uimenu(app.DataMenu);
            app.SaveplotsMenu.Text = 'Save plots';
            app.ExportplotMenu = uimenu(app.DataMenu);
            app.ExportplotMenu.Text = 'Export plot';
            app.ExportallplotsMenu = uimenu(app.DataMenu);
            app.ExportallplotsMenu.Text = 'Export all plots';
            app.PlotsMenu = uimenu(app.UIFigure);
            app.PlotsMenu.Text = 'Plots';
            app.AddnewplotMenu = uimenu(app.PlotsMenu);
            app.AddnewplotMenu.Text = 'Add new plot';
            app.xyMenu = uimenu(app.AddnewplotMenu);
            app.xyMenu.Text = 'x, y';
            app.xyy2Menu = uimenu(app.AddnewplotMenu);
            app.xyy2Menu.Text = 'x, y, y2';
            app.xyx2y2Menu = uimenu(app.AddnewplotMenu);
            app.xyx2y2Menu.Text = 'x,y, x2, y2';
            app.TensileShearMenu = uimenu(app.AddnewplotMenu);
            app.TensileShearMenu.Text = 'Tensile / Shear';
            app.DeletecurrentplotMenu = uimenu(app.PlotsMenu);
            app.DeletecurrentplotMenu.Text = 'Delete current plot';
            app.LegendMenu = uimenu(app.UIFigure);
            app.LegendMenu.Text = 'Legend';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.TypeofplotandnumberofplotLabel = uilabel(app.GridLayout);
            app.TypeofplotandnumberofplotLabel.Text = 'Type of plot and number of plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end