classdef AppTypeSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        UITable                matlab.ui.control.Table
        NewButton              matlab.ui.control.Button
        DelButton              matlab.ui.control.Button
        PropertiesoftypePanel  matlab.ui.container.Panel
        CloneButton            matlab.ui.control.Button
        ClonnedCheckBox        matlab.ui.control.CheckBox
        SaveButton             matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        SupObj;
        MasterFolder;
        TypesTable table;
        DataTypes;
        IconFolder char; % Description
        ContainerTypes categorical;
        SufixTypes categorical;
        StrDataTypes categorical; 
        CurrRowSel (1,1) double;
    end
    
    properties (Access = private)

    end
    
    methods (Access = private)
        
        function SetOptions(app)
            app.StrDataTypes=["MainTable","Zedo","Press"];
            app.ContainerTypes=["File","Folder"];
            app.SufixTypes=[".xls",".xlsx",".csv",".txt",".bin","~"];
        end
        
        function AddRow(app)
            row=size(app.UITable.Data,1)+1;
            
            Container=app.ContainerTypes(1);
            KeyWord="";
            Sufix=app.SufixTypes(1);
            DataType=app.StrDataTypes(1);
            
            T=table(Container,KeyWord,Sufix,DataType);
            if isa(app.UITable.Data,'table')
                app.UITable.Data=[app.UITable.Data; T];
            else
                app.UITable.Data=T;
            end
            
            n=numel(app.DataTypes)+1;
            
            app.DataTypes{n}=MainTable('test',app.PropertiesoftypePanel);
            %SetGuiParent(app.DataTypes{n},app.PropertiesoftypePanel);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, SupObj, MasterFolder)
            app.SupObj=SupObj;
            app.MasterFolder=MasterFolder;
            app.UIFigure.Name='Type selector table';
            SetOptions(app);
            
            app.UITable.ColumnEditable=true;
            
            app.IconFolder=[MasterFolder 'GUI\Icons\'];
            
            app.NewButton.Icon=[app.IconFolder 'plus_sign.gif'];
            app.NewButton.Text='';
            
            app.DelButton.Icon=[app.IconFolder 'cancel_sign.gif'];
            app.DelButton.Text='';
            
            AddRow(app);
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
            
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)

            AddRow(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %obj.MeaObj.TableTypes=obj.TableTypes;
            answer = questdlg('Do you want to save and close the current data types?', ...
                'Saving window','Yes','No','No');
            % Handle response
            switch answer
                case 'Yes'
                    SetDataTypesTable(app.SupObj,app.TypesTable);
                    delete(app);
                case 'No'
            end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if app.CurrRowSel>0
                app.CurrRowSel= event.Indices(1);                     
            else
                app.CurrRowSel= 1; 
            end
            DrawTypeOption(app.DataTypes{app.CurrRowSel});
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Container'; 'KeyWord'; 'Sufix'; 'DataType'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.NewButton = uibutton(app.GridLayout, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.DelButton = uibutton(app.GridLayout, 'push');
            app.DelButton.Text = 'Del';
            app.PropertiesoftypePanel = uipanel(app.GridLayout);
            app.PropertiesoftypePanel.Title = 'Properties of type';
            app.CloneButton = uibutton(app.GridLayout, 'push');
            app.CloneButton.Text = 'Clone';
            app.ClonnedCheckBox = uicheckbox(app.GridLayout);
            app.ClonnedCheckBox.Enable = 'off';
            app.ClonnedCheckBox.Text = 'Clonned';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTypeSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end