classdef AppTypeSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        UITable                       matlab.ui.control.Table
        NewButton                     matlab.ui.control.Button
        DelButton                     matlab.ui.control.Button
        PropertiesoftypePanel         matlab.ui.container.Panel
        CloneButton                   matlab.ui.control.Button
        ClonnedCheckBox               matlab.ui.control.CheckBox
        SaveButton                    matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        QuickOperationDropDownLabel   matlab.ui.control.Label
        QuickOperationDropDown        matlab.ui.control.DropDown
        ExperimentnameEditFieldLabel  matlab.ui.control.Label
        ExperimentnameEditField       matlab.ui.control.EditField
    end

    
    properties (Access = public)
        ProjObj;
        MeaObj;
        MasterFolder;
        TypesTable table;
        DataTypes;
        IconFolder char; % Description
        ContainerTypes categorical;
        SufixTypes categorical;
        StrDataTypes categorical; 
        CurrRowSel (1,1) double;
        nType (1,1) double;
        StartType;
        Parent;
        TreeNode;
        MainObj;
        ExpObj;
    end
    
    properties (Access = private)

    end
    
    methods (Access = private)
        
        function SetOptions(app)
            app.StrDataTypes=DataFrame.GetTypes;
            app.ContainerTypes=["File","Folder"];
            app.SufixTypes=[".xls",".xlsx",".csv",".txt",".bin","~"];
        end
        
        function AddRow(app)
            row=size(app.UITable.Data,1)+1;
            
            Container=app.ContainerTypes(1);
            KeyWord="";
            Sufix=app.SufixTypes(1);
            DataType=app.StrDataTypes(1);
            
            T=table(Container,KeyWord,Sufix,DataType);
            if isa(app.UITable.Data,'table')
                app.UITable.Data=[app.UITable.Data; T];
            else
                app.UITable.Data=T;
            end
            
            app.nType=app.nType+1;
            %SetType(app,app.nType,StrDataType);
            app.DataTypes{app.nType}=MainTable;
            SetGuiParent(app.DataTypes{app.nType},app.PropertiesoftypePanel);
            DrawTypeOption(app.DataTypes{app.nType});
        end
        
        function GiveGuiParent(app)
            for i =1:numel(app.DataTypes)
                app.nType=app.nType+1;
                SetGuiParent(app.DataTypes{i},app.PropertiesoftypePanel);
            end
        end
        
        function SetType(app,Idx,StrDataType)
            
            if ~isa(app.DataTypes{Idx},char(StrDataType))
                app.DataTypes{Idx}=OperLib.CreateType(StrDataType);
            end
            SetGuiParent(app.DataTypes{Idx},app.PropertiesoftypePanel);
        end
        
        function LoadData2Tables(app)
            if app.StartType==1
                if app.MeaObj.ClonedTypes==true
                    app.UITable.Data=app.ProjObj.TypeTable(:,1:end-1);
                    app.DataTypes=table2array(app.ProjObj.TypeTable(:,end))';
                else
                    app.UITable.Data=app.MeaObj.TypeTable(:,1:end-1);
                    app.DataTypes=table2array(app.MeaObj.TypeTable(:,end))';
                end
            else
                app.UITable.Data=app.ProjObj.DataTypesTable(:,1:end-1);
                app.DataTypes=table2array(app.ProjObj.DataTypesTable(:,end))';
            end
            GiveGuiParent(app);
        end
        
        function OutTable = GetOutTable(app)
            OutTable=[app.UITable.Data, table(app.DataTypes','Variablenames',{'TypesObj'})];
            SetTypeSettings(app.ExpObj,OutTable);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj, MasterFolder, StartType, ExpHan)
            app.StartType=StartType;
            app.ExpObj=ExpHan;
            %app.MainObj=app.Parent.Parent.Parent;
            
            if StartType==1 %started from app
                app.Parent=obj;
                app.TreeNode=app.Parent.ExpMainNode;
                %app.ClonnedCheckBox.Value=app.MeaObj.ClonedTypes;
            else %started from script
                
            end
            
            app.MasterFolder=MasterFolder;
            app.UIFigure.Name='Type selector table';
            SetOptions(app);
            
            app.UITable.ColumnEditable=true;
            
            app.IconFolder=[MasterFolder '\Master\GUI\Icons\'];
            
            app.NewButton.Icon=[app.IconFolder 'plus_sign.gif'];
            app.NewButton.Text='';
            
            app.DelButton.Icon=[app.IconFolder 'cancel_sign.gif'];
            app.DelButton.Text='';
            app.nType=0;
            
            if ExpHan.Status==1
                app.UITable.Data=ExpHan.TypeSettings(:,1:end-1);
                app.ExperimentnameEditField.Value=ExpHan.Name;
                app.DataTypes=ExpHan.TypeSettings{:,end}';
            else
                
            end
            
%             if app.ProjObj.MeasCount>0
%                 if isa(app.MeaObj.TypeTable,'table')
%                     LoadData2Tables(app);
%                 else
%                     AddRow(app);
%                 end
%             end
            
        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)

            delete(app);
        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)

            AddRow(app);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            OutTable = GetOutTable(app);
            
%             if app.StartType==1
%                 SetDataTypes(app.MeaObj,OutTable);
%             else
%                 SetDataTypesTable(app.ProjObj,OutTable);
%             end
            
            delete(app);
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            if app.CurrRowSel>0
                app.CurrRowSel= event.Indices(1);                     
            else
                app.CurrRowSel= 1; 
            end
            
            if event.Indices(2)==4
                SetType(app,app.CurrRowSel,app.UITable.Data{app.CurrRowSel,4});
            end
            
            DrawTypeOption(app.DataTypes{app.CurrRowSel});
        end

        % Button pushed function: DelButton
        function DelButtonPushed(app, event)
            if app.CurrRowSel>0
                app.UITable.Data(app.CurrRowSel,:)=[];
                app.DataTypes(app.CurrRowSel)=[];
                app.nType=app.nType-1;
            end
        end

        % Button pushed function: CloneButton
        function CloneButtonPushed(app, event)
            if app.StartType==1
                app.ClonnedCheckBox.Value=1;
                app.MeaObj.ClonedTypes=1;
                LoadData2Tables(app);
            end
        end

        % Value changed function: QuickOperationDropDown
        function QuickOperationDropDownValueChanged(app, event)
            value = app.QuickOperationDropDown.Value;
            switch lower(value)
                case '1' %load table
                    [file,path] = uigetfile('*.mat');
                    load([path '\' file]);
                    %OutTable=[app.UITable.Data, table(app.DataTypes','Variablenames',{'TypesObj'})];
                    PT=OutTable(:,1:end-1);
                    app.UITable.Data=PT;
                    TMP=table2array(OutTable(:,end))';
                    app.DataTypes=TMP;
                case '2' %export tble
                    [file,path,indx] = uiputfile('animinit.mat');
                    OutTable = GetOutTable(app);
                    try
                        save([path file],"OutTable")
                    catch ME
                        
                    end
                case '3' %force table
                    if app.StartType==2
                        OutTable = GetOutTable(app);
                        CloneDataType(app.ProjObj,OutTable);
                    end
                otherwise
               
            end
            app.QuickOperationDropDown.Value='0';
        end

        % Value changed function: ExperimentnameEditField
        function ExperimentnameEditFieldValueChanged(app, event)
            value = app.ExperimentnameEditField.Value;
            app.ExpObj.TreeNode.Text=value;
            app.ExpObj.Name=value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Container'; 'KeyWord'; 'Sufix'; 'DataType'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.NewButton = uibutton(app.GridLayout, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.DelButton = uibutton(app.GridLayout, 'push');
            app.DelButton.ButtonPushedFcn = createCallbackFcn(app, @DelButtonPushed, true);
            app.DelButton.Text = 'Del';
            app.PropertiesoftypePanel = uipanel(app.GridLayout);
            app.PropertiesoftypePanel.Title = 'Properties of type';
            app.CloneButton = uibutton(app.GridLayout, 'push');
            app.CloneButton.ButtonPushedFcn = createCallbackFcn(app, @CloneButtonPushed, true);
            app.CloneButton.Text = 'Clone';
            app.ClonnedCheckBox = uicheckbox(app.GridLayout);
            app.ClonnedCheckBox.Enable = 'off';
            app.ClonnedCheckBox.Text = 'Clonned';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.GridLayout, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.QuickOperationDropDownLabel = uilabel(app.GridLayout);
            app.QuickOperationDropDownLabel.Text = 'Quick Operation';
            app.QuickOperationDropDown = uidropdown(app.GridLayout);
            app.QuickOperationDropDown.Items = {'-', 'Load table', 'Export table', 'Force TypeTable 2 all'};
            app.QuickOperationDropDown.ItemsData = {'0', '1', '2', '3'};
            app.QuickOperationDropDown.ValueChangedFcn = createCallbackFcn(app, @QuickOperationDropDownValueChanged, true);
            app.QuickOperationDropDown.Value = '0';
            app.ExperimentnameEditFieldLabel = uilabel(app.GridLayout);
            app.ExperimentnameEditFieldLabel.Text = 'Experiment name:';
            app.ExperimentnameEditField = uieditfield(app.GridLayout, 'text');
            app.ExperimentnameEditField.ValueChangedFcn = createCallbackFcn(app, @ExperimentnameEditFieldValueChanged, true);
            app.ExperimentnameEditField.Value = 'name xy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppTypeSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end