classdef EditProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SaveAndExitButton            matlab.ui.control.Button
        CLine                        matlab.ui.control.Label
        NameofprojectEditFieldLabel  matlab.ui.control.Label
        NameTextField                matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Name char; % name of project 
        Parent; %handle of mother app        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MotherHandle)
            app.Parent=MotherHandle;            
        end

        % Button pushed function: SaveAndExitButton
        function SaveAndExitButtonPushed(app, event)
           name = app.NameTextField.Value;
           if ischar(name)
               %yes its is string
               if ~ismember(name,{'/','\','|'})
                   %the path is ok
                   app.Name=name;

                   
                   [status,ID]=CreateProject(app.Parent,app.Name);

                   switch status
                       case 1 %its ok
                        app.CLine.Text='The selected name of project is OK!';
%                         pause(1.5);
                        FillTree(app.Parent);
%                         InitiateSelectorCBX(app.Parent,ID);
%                         app.UIFigure.Visible='off';
%                         app.MA.UIFigure.Visible='on';
                        app.delete;
                       case 4 %some error
                          app.CLine.Text=sprintf('Project of this name already exists,\nchoose another name!'); 
                   end
               else
                   %the path is not ok
                   app.CLine.Text='The selected name contains forbidden letters (\,|,/)';
               end
           else
               %no its not a string
               app.CLine.Text='The selected name is not a string!';
           end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SaveAndExitButton = uibutton(app.UIFigure, 'push');
            app.SaveAndExitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAndExitButtonPushed, true);
            app.SaveAndExitButton.Text = 'Save and exit';
            app.CLine = uilabel(app.UIFigure);
            app.CLine.Text = 'Info for user';
            app.NameofprojectEditFieldLabel = uilabel(app.UIFigure);
            app.NameofprojectEditFieldLabel.Text = 'Name of project:';
            app.NameTextField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EditProject(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end